classdef specAnalyser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        SpectrumAnalyzerLabel           matlab.ui.control.Label
        ZoomInOutButton                 matlab.ui.control.StateButton
        ZoomInOutButton_2               matlab.ui.control.StateButton
        RunButton                       matlab.ui.control.StateButton
        StopButton                      matlab.ui.control.StateButton
        TunedFrequencyGHzEditFieldLabel  matlab.ui.control.Label
        TunedFrequencyGHzEditField      matlab.ui.control.NumericEditField
        DecimationFactorEditFieldLabel  matlab.ui.control.Label
        DecimationFactorEditField       matlab.ui.control.NumericEditField
        AveragingCheckBox               matlab.ui.control.CheckBox
        GainEditFieldLabel              matlab.ui.control.Label
        GainEditField                   matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Callback function
        function StartButtonPushed(app, event)
              %Function no longer exists
        end

        % Value changed function: ZoomInOutButton
        function ZoomInOutButtonValueChanged(app, event)
            value = app.ZoomInOutButton.Value;
            
            if (value==1)
                zoom(app.UIAxes,'on');
            else
                zoom(app.UIAxes,'off')
            end
        end

        % Value changed function: ZoomInOutButton_2
        function ZoomInOutButton_2ValueChanged(app, event)
            value = app.ZoomInOutButton_2.Value;
            if (value==1)
                zoom(app.UIAxes_2,'on');
            else
                zoom(app.UIAxes_2,'off')
            end
            
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            value = app.RunButton.Value;
             global tcpipObj;
%             global appObj;
%             appObj= app;
%             global freq;
%             global decfactor;
%             global avg;
%             global gain;
            IP= '127.0.0.1';                      %IP address
            port = 5000;                          %Port value
            tcpipObj = tcpip(IP, port);
            tcpipObj.InputBufferSize = 4096;      %Buffer Size
            tcpipObj.Terminator = 0;
            fopen(tcpipObj);                      %Opening connection 
            
            if (value==1)
               RP_signalcap(app.TunedFrequencyGHzEditField.Value, app.DecimationFactorEditField.Value,app.AveragingCheckBox.Value,app.GainEditField.Value,tcpipObj,app);
            end
            
            value=0;
        end

        % Value changed function: StopButton
        function StopButtonValueChanged(app, event)
            global tcpipObj;
            value = app.StopButton.Value;
            if(value==1)
                RP_stop(tcpipObj,app);
            end 
            value=0;
        end

        % Value changed function: TunedFrequencyGHzEditField
        function TunedFrequencyGHzEditFieldValueChanged(app, event)
            value = app.TunedFrequencyGHzEditField.Value;
            global freq;
            freq= value; 
        end

        % Value changed function: DecimationFactorEditField
        function DecimationFactorEditFieldValueChanged(app, event)
            value = app.DecimationFactorEditField.Value;
            global decfactor;
            decfactor= value;
        end

        % Value changed function: GainEditField
        function GainEditFieldValueChanged(app, event)
            value = app.GainEditField.Value;
            global gain;
            gain= value;
        end

        % Value changed function: AveragingCheckBox
        function AveragingCheckBoxValueChanged(app, event)
            value = app.AveragingCheckBox.Value;
            global avg;
            avg= value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Time Domain')
            xlabel(app.UIAxes, 'Time(t)')
            ylabel(app.UIAxes, 'X(t)')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Frequency Domain')
            xlabel(app.UIAxes_2, 'Frequency(k)(MHz)')
            ylabel(app.UIAxes_2, 'X(k)')
            app.SpectrumAnalyzerLabel = uilabel(app.UIFigure);
            app.SpectrumAnalyzerLabel.Text = 'Spectrum Analyzer';
            app.ZoomInOutButton = uibutton(app.UIFigure, 'state');
            app.ZoomInOutButton.ValueChangedFcn = createCallbackFcn(app, @ZoomInOutButtonValueChanged, true);
            app.ZoomInOutButton.Text = 'Zoom In/Out';
            app.ZoomInOutButton_2 = uibutton(app.UIFigure, 'state');
            app.ZoomInOutButton_2.ValueChangedFcn = createCallbackFcn(app, @ZoomInOutButton_2ValueChanged, true);
            app.ZoomInOutButton_2.Text = 'Zoom In/Out';
            app.RunButton = uibutton(app.UIFigure, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.StopButton = uibutton(app.UIFigure, 'state');
            app.StopButton.ValueChangedFcn = createCallbackFcn(app, @StopButtonValueChanged, true);
            app.StopButton.Text = 'Stop';
            app.TunedFrequencyGHzEditFieldLabel = uilabel(app.UIFigure);
            app.TunedFrequencyGHzEditFieldLabel.Text = 'Tuned Frequency(GHz)';
            app.TunedFrequencyGHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.TunedFrequencyGHzEditField.ValueChangedFcn = createCallbackFcn(app, @TunedFrequencyGHzEditFieldValueChanged, true);
            app.DecimationFactorEditFieldLabel = uilabel(app.UIFigure);
            app.DecimationFactorEditFieldLabel.Text = 'Decimation Factor';
            app.DecimationFactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.DecimationFactorEditField.ValueChangedFcn = createCallbackFcn(app, @DecimationFactorEditFieldValueChanged, true);
            app.AveragingCheckBox = uicheckbox(app.UIFigure);
            app.AveragingCheckBox.ValueChangedFcn = createCallbackFcn(app, @AveragingCheckBoxValueChanged, true);
            app.AveragingCheckBox.Text = 'Averaging';
            app.GainEditFieldLabel = uilabel(app.UIFigure);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.UIFigure, 'numeric');
            app.GainEditField.ValueChangedFcn = createCallbackFcn(app, @GainEditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = specAnalyser

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end